classdef send_for_processing < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        fig             matlab.ui.Figure
        src_label       matlab.ui.control.Label
        src_entry       matlab.ui.control.EditField
        trg_label       matlab.ui.control.Label
        trg_entry       matlab.ui.control.EditField
        start_btn       matlab.ui.control.Button
        quit_btn        matlab.ui.control.Button
        log_text_area   matlab.ui.control.TextArea
        src_browse_btn  matlab.ui.control.Button
        trg_browse_btn  matlab.ui.control.Button
    end

    
    properties (Access = private)
        %% Keeping track of valid paths
        src_valid = false;
        trg_valid = false;
        not_found_label = '404: Not Found!' % Description
        
        %% Navigational shortcuts
        last_path = '.';
        
        %% Behavior
        delay = 3; % seconds
        
        %% Control start/stop
        quitting = false; % tells the transfer to finish
        
        %% Colors
        def_fg = [0.90, 0.90, 0.90]; % Default foreground color
        def_bg = [0.15, 0.15, 0.15]; % Default background color
        err_fg = [1.00, 0.00, 0.00]; % Error color 
    end
    
    methods (Access = private)
        
        
        function src_and_trg_exist = validateSrcAndTrgPaths(app)
            % todo: could maybe do this with a for loop if we tried
            src_and_trg_exist = [false, false];
            src_path = app.src_entry.Value;
            trg_path = app.trg_entry.Value;
            
            % Check if they're the same
            src_and_trg_same = strcmp(src_path, trg_path) || contains(src_path, trg_path);
            if src_and_trg_same
                uialert(app.fig, 'Paths may not be the same', 'Paths equal', 'icon', 'error')
            end
            
            % Validate paths
            src_and_trg_exist(1) = exist(src_path, 'dir') ~=0;
            src_and_trg_exist(2) = exist(app.trg_entry.Value, 'dir') ~=0;
            
            if ~any(src_and_trg_exist)
                flashError(app, {app.src_entry; app.trg_entry})
                not_found_text = vertcat({app.not_found_label}, {src_path; trg_path});
            elseif ~src_and_trg_exist(1)
                flashError(app, {app.src_entry})
                not_found_text = vertcat({app.not_found_label}, {src_path});
            elseif ~src_and_trg_exist(2)
                flashError(app, {app.trg_entry})
                not_found_text = vertcat({app.not_found_label}, {trg_path});
            end
            if any(~src_and_trg_exist)
                set(app.log_text_area, 'Value', not_found_text)
            end
        end
        
        function transferFiles(app, src_path, trg_path)
            % Disable start_btn to prevent multiple callbacks
            set(app.start_btn, 'Enable', 'off');
            set(app.src_entry, 'Enable', 'off');
            set(app.trg_entry, 'Enable', 'off');
            set(app.src_browse_btn, 'Enable', 'off');
            set(app.trg_browse_btn, 'Enable', 'off');
            
            % Get source root and copy everything to target root
            src_parts = strsplit(src_path, filesep);
            src_root = src_parts{end};
            first_iteration = true;
            try
                while isvalid(app.fig) && ~app.quitting
                
                    % Let user know that transfer has started
                    if first_iteration
                        set(app.log_text_area, 'Value', 'Running...');
                        first_iteration = false;
                    end
                    pause(app.delay)
                    
                    %% Recursive file search
                    all_src_files = subdir(src_path);
                    % Keep track of what's been taken care of
                    file_copied = false(size(all_src_files));
                    
                    % Start checking file statuses
                    for ii=1:numel(all_src_files)
                        % Check to see if we can stop
                        if app.quitting
                            break;
                        end
                        
                        % Construct output names
                        out_fname = all_src_files(ii).name(numel(src_path)+1:end);
                        out_ffname = fullfile(trg_path, src_root, out_fname);
                        % Make the path if it doesn't exist
                        out_path = fileparts(out_ffname);
                        if exist(out_path, 'dir') == 0
                            [status, msg] = mkdir(out_path);
                            if status
                                set(app.log_text_area, 'Value', ...
                                    sprintf('Creating %s', out_path));
                            else
                                set(app.log_text_area, 'Text', msg);
                                continue;
                            end
                        end
                        
                        % Copy the file if it doesn't exist or a newer version
                        % is available
                        file_exists = exist(out_ffname, 'file') ~= 0;
                        if file_exists
                            file_info = dir(out_ffname);
                        end
                        if ~file_exists || all_src_files(ii).datenum > file_info.datenum
                            [this_path,this_name,this_ext] = fileparts(all_src_files(ii).name);
                            % Determine if the file is closed
                            if strcmpi(this_ext, '.avi')
                                % .avi's are a little awkward
                                file_ready = isReadable(this_path, {[this_name, this_ext]});
                            else
                                % Don't copy until it's closed
                                [~,fa] = fileattrib(all_src_files(ii).name);
                                file_ready = fa.UserWrite;
                            end
                            
                            % If it's ready, go ahead and copy
                            if file_ready
                                [status, msg] = copyfile(all_src_files(ii).name, out_ffname);
                                if status
                                    set(app.log_text_area, 'Value', ...
                                        {'Copying';all_src_files(ii).name;'to';out_ffname});
                                    file_copied(ii) = true;
                                else
                                    set(app.log_text_area, 'Value', msg);
                                    continue;
                                end
                            end
                        else
                            % This file does not need to be copied
                            file_copied(ii) = true;
                        end                    
                    end % All files examined
                    remaining_files = all_src_files(~file_copied);
                    if isempty(remaining_files)
                        set(app.log_text_area, 'Value', ...
                        'All caught up.');
                    else
                        set(app.log_text_area, 'Value', ...
                            [{'Waiting for: '}; {remaining_files.name}']);
                    end
                end
            catch me
                if ~strcmp(me.identifier, 'MATLAB:class:InvalidHandle')
                    rethrow(me);
                end
            end
        end
        
        function flashError(~, handle)
            DURATION = 1.5; % seconds
            CYCLES = 3;
            DT = 50E-3; % seconds
            t = linspace(0, pi*CYCLES, DURATION/DT);
            r = abs(sin(t));
            beep;
            for ii=1:length(t)
                for jj=1:numel(handle)
                    set(handle{jj}, 'backgroundcolor', [r(ii), 0, 0]);
                end
                pause(DT);
            end
        end
        
        function toggleEditableEnable(app, state)
            set(app.src_entry,      'Enable', state);
            set(app.trg_entry,      'Enable', state);
            set(app.src_browse_btn, 'Enable', state);
            set(app.trg_browse_btn, 'Enable', state);
            set(app.start_btn,      'Enable', state);
            set(app.src_label,      'Enable', state);
            set(app.trg_label,      'Enable', state)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: quit_btn
        function quit_btnButtonPushed(app, event)
            app.quitting = true;
            delete(app)
        end

        % Button pushed function: src_browse_btn
        function src_browse_btnButtonPushed(app, event)
            dlg_title = 'Select dataset to transfer';
            out_path = uigetdir(app.last_path, dlg_title);
            if isnumeric(out_path)
                return;
            end
            set(app.src_entry, 'Value', out_path);
            app.last_path = out_path;
        end

        % Button pushed function: trg_browse_btn
        function trg_browse_btnButtonPushed(app, event)
            dlg_title = 'Select destination';
            out_path = uigetdir(app.last_path, dlg_title);
            if isnumeric(out_path)
                return;
            end
            set(app.trg_entry, 'Value', out_path);
            app.last_path = out_path;
        end

        % Button pushed function: start_btn
        function start_btnButtonPushed(app, event)
            toggleEditableEnable(app, 'off')
            if all(validateSrcAndTrgPaths(app))
                transferFiles(app, app.src_entry.Value, app.trg_entry.Value)
            else
                toggleEditableEnable(app, 'on')
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.fig = uifigure('Visible', 'off');
            app.fig.Name = 'Send for processing v2.0';
            app.src_label = uilabel(app.fig);
            app.src_label.Text = 'AO PC Path:';
            app.src_entry = uieditfield(app.fig, 'text');
            app.src_entry.Value = 'D:\';
            app.trg_label = uilabel(app.fig);
            app.trg_label.Text = 'Processing PC Path:';
            app.trg_entry = uieditfield(app.fig, 'text');
            app.trg_entry.Value = '\\141.106.183.144\3rd_Circle\';
            app.start_btn = uibutton(app.fig, 'push');
            app.start_btn.ButtonPushedFcn = createCallbackFcn(app, @start_btnButtonPushed, true);
            app.start_btn.Tooltip = {'Begin copying files'};
            app.start_btn.Text = 'Start';
            app.quit_btn = uibutton(app.fig, 'push');
            app.quit_btn.ButtonPushedFcn = createCallbackFcn(app, @quit_btnButtonPushed, true);
            app.quit_btn.Text = 'Quit';
            app.log_text_area = uitextarea(app.fig);
            app.log_text_area.Editable = 'off';
            app.log_text_area.Enable = 'off';
            app.log_text_area.Value = {'Enter paths to begin...'};
            app.src_browse_btn = uibutton(app.fig, 'push');
            app.src_browse_btn.ButtonPushedFcn = createCallbackFcn(app, @src_browse_btnButtonPushed, true);
            app.src_browse_btn.Text = 'Browse...';
            app.trg_browse_btn = uibutton(app.fig, 'push');
            app.trg_browse_btn.ButtonPushedFcn = createCallbackFcn(app, @trg_browse_btnButtonPushed, true);
            app.trg_browse_btn.Text = 'Browse...';
            % Show the figure after all components are created
            app.fig.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = send_for_processing

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.fig)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.fig)
        end
    end
end