classdef montage_display_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1            matlab.ui.Figure
        montage_ax         matlab.ui.control.UIAxes
        modality_dd        matlab.ui.control.DropDown
        prog_ax            matlab.ui.control.UIAxes
        break_text         matlab.ui.control.TextArea
        write_btn          matlab.ui.control.Button
        latest_vidnum_txt  matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        buffer_px {mustBePositive, mustBeFinite, mustBeReal} = 25; % Description
        default_modality = 'confocal';
        break_label = 'Breaks'; % Description
        disjoints = []; % Updated montage structure
        latest_vidnum = []; % for highlighting most recent images
    end
    
    properties (Access = public)
        live_data = []; % Contains the montages to display and other calibration data
        canvas_hw = [0,0] % size of canvas (height, width)
    end
    
    methods (Access = public)
        
        function updateDisplay(app)
            % Switch to the requested display modality
            if isempty(app.live_data)
                return;
            end
            montages = app.live_data.mon.montages;
            montages = switchChannel(app, montages);
            % Convert to px
            montages = deg2px(app, montages);
            if isempty(montages)
                % Insufficient data
                return;
            end
            
            % Determine optimal arrangement of disjoints
            [montages, montage_origins] = buildCanvas(app, montages);
            
            % Fill canvas with images
            canvas = populateCanvas(app, montages);
            app.disjoints = montages;
            
            % Update last video added
            updateLatestVidnum(app)
            
            % Display
            imshow(canvas, 'border', 'tight', 'parent', app.montage_ax);
            app.montage_ax.BackgroundColor = [0,0,0];
            drawLatestPatches(app, montages)
            drawDisjointPatches(app, montage_origins)
            drawnow;
            
            % Update disjoint suggestions
            updateBreakDisplay(app)
            
            % Reset waitbar
            app.updateProgress(0)
            
        end
        
        function outputMontage(app)
            out_path = uigetdir('.', 'Select folder for output');
            if isnumeric(out_path)
                return;
            end
            
            % Get output modalities
            mods = app.modality_dd.Items;
            
            % Get all images for progress indication
            total_n_imgs = 0;
            k=0;
            for ii=1:numel(app.disjoints)
                total_n_imgs = total_n_imgs + numel(app.disjoints(ii).txfms) * numel(mods);
            end
            
            % Start constructing images
            for ii=1:numel(app.disjoints)
                xyhw = txfms2mat(app, app.disjoints(ii).txfms);
                
                for jj=1:numel(app.disjoints(ii).txfms)
                    % Reset canvas each time
                    canvas = zeros(app.canvas_hw(1), app.canvas_hw(2), 2, 'uint8');
                    [img_path, img_name, img_ext] = fileparts(app.disjoints(ii).txfms{jj}{1});
                    scale = xyhw(jj,3:4);
                    
                    for mm=1:numel(mods)
                        % Pick image
                        this_img_name = strrep(img_name, app.modality_dd.Value, mods{mm});
                        this_img_ffname = fullfile(img_path, [this_img_name, img_ext]);
                        if exist(this_img_ffname, 'file') == 0
                            warning('%s does not exist', this_img_name);
                            continue;
                        end
                        
                        % Read image, resize
                        img = imresize(imread(this_img_ffname), scale, 'bicubic');
                        im_size = size(img);
            
                        % Add to canvas
                        y_idx = round(xyhw(jj,2):xyhw(jj,2)+im_size(1)-1);
                        x_idx = round(xyhw(jj,1):xyhw(jj,1)+im_size(2)-1);
                        canvas(y_idx, x_idx, 1) = img;
                        canvas(y_idx, x_idx, 2) = 255;
                        
                        % Modify output name for easy grouping
                        saveTif(canvas, out_path, [this_img_name, img_ext]);
                        
                        k=k+1;
                        updateProgress(app, k/total_n_imgs);
                    end
                end
            end
        end
    end
    
    methods (Access = private)
        
        function montages = switchChannel(app, montages)
            % Replace modality string for disp_mod
            for ii=1:numel(montages)
                for jj=1:numel(montages(ii).txfms)
                    img_ffname = montages(ii).txfms{jj}{1};
                    [img_path, img_name, img_ext] = fileparts(img_ffname);
                    % todo: this will definitely break as soon as other
                    % wavelengths are included
                    out_name = strrep(img_name, app.default_modality, get(app.modality_dd, 'Value'));
                    % Overwrite with display name
                    % todo: this will crash if the expected modality doesn't exist
                    % Not sure if that would ever occur
                    montages(ii).txfms{jj}{1} = fullfile(img_path, [out_name, img_ext]);
                end
            end
        end
        
        function montages = deg2px(app, montages)
            %% Determine scale in pixels/degree
            % (input is degrees)
            % Find the minimum FOV used in this montage
            min_fov = inf;
            for ii=1:numel(montages)
                for jj=1:numel(montages(ii).txfms)
                    img_ffname = montages(ii).txfms{jj}{1};
                    [~,img_name, img_ext] = fileparts(img_ffname);
                    kv = findImageInVidDB(app.live_data, [img_name, img_ext]);
                    if isempty(kv) % This video hasn't finished updating
                        continue;
                    end
                    this_fov = app.live_data.vid.vid_set(kv(1)).fov;
                    if this_fov < min_fov
                        min_fov = this_fov;
                    end
                end
            end
            if isinf(min_fov) % Database is not ready
                montages = [];
                return;
            end
            
            % Get the pixels per degree that was used for this montage
            this_ppd = app.live_data.cal.dsin([app.live_data.cal.dsin.fov] == min_fov).ppd;
            % overwrite the pixel units with degree values
            for ii=1:numel(montages)
                for jj=1:numel(montages(ii).txfms)
                    for kk=2:5
                        montages(ii).txfms{jj}{kk} = ...
                            montages(ii).txfms{jj}{kk}.*this_ppd;
                    end
                end
            end
        end
        
        
        function [montages, mon_origins_yxhw] = buildCanvas(app, montages)
            % This is an attempt to construct a montage in an efficient
            % retina-centric fashion, where disjoints are placed roughly
            % where they belong, separated by a small gap. 
            % Everything in this function is in Euclidean-space unless
            % otherwise noted
            [montages, closest_pos] = proximityToCenterSort(app, montages);
            
            % Determine angles between center and each disjoint
            thetas = atan(closest_pos(:,2)./closest_pos(:,1)); 
            thetas(isnan(thetas)) = 0;
            
            % Keep track of montage origins
            mon_origins_yxhw = zeros(numel(montages), 4);
            
%             % Track edges with polar coords (relative to center)
%             minx_tr = zeros(numel(montages), 2); % left edges, theta, rho
%             maxx_tr = minx_tr;
%             miny_tr = minx_tr;
%             maxy_tr = minx_tr;
            
            % Track bounds and anchors with xywh
            xyhw = zeros(numel(montages), 4);
            all_anchors = zeros(3,3,2,numel(montages));
            for ii=1:numel(montages)
                % Get the span of this montage
                span_hw = getMontageSpan(app, montages(ii));
                half_diagonal = 0.5*sqrt(span_hw(1)^2 + span_hw(2)^2);
                % Determine a center of mass for this disjoint that doesn't
                % overlap with any others in approximately the right
                % retinal location
                if ii==1
                    com_xy = [0, 0];
                else
                    % Find the anchor farthest along the current axis
                    [xx, yy] = pol2cart(thetas(ii), 1e6); % Just a crazy high number
                    min_dist = inf;
                    for jj=1:ii-1
                        ref_xx = all_anchors(:,:,1,jj);
                        ref_yy = all_anchors(:,:,2,jj);
                        ref_xy = [ref_xx(:), ref_yy(:)];
                        [new_min_dist, I] = min(pdist2([xx,yy], ref_xy));
                        if new_min_dist < min_dist
                            min_dist = new_min_dist;
                            farthest_anchor = ref_xy(I, :);
                        end
                    end
                    % From the farthest anchor, draw a line of length
                    % half_diagonal + buffer along the current axis
                    [x, y] = pol2cart(thetas(ii), half_diagonal + app.buffer_px);
                    com_xy = [farthest_anchor(1) + x, farthest_anchor(2) + y];
                    % todo: check that these don't overlap with any others
                end
                xyhw(ii, :) = [com_xy, span_hw];
                all_anchors(:,:,:,ii) = getAnchors(app, xyhw(ii, :));
                
                
                  % Get center-of-mass for this disjoint
%                 if ii>1
%                     % Pick a point far along the axis of this montage and find
%                     % the nearest edge
%                     all_edges = [minx_tr; miny_tr; maxx_tr; maxy_tr];
%                     d = max(all_edges(:,2))*100;
%                     
%                     current_min_dist = inf;
%                     for jj=1:numel(montages)-1
%                         these_edges = [
%                             minx_tr(jj, :);
%                             maxx_tr(jj, :);
%                             miny_tr(jj, :);
%                             maxy_tr(jj, :)];
%                         [min_dist, I] = min(polardist([thetas(ii), d], these_edges));
%                         if min_dist < current_min_dist
%                             m_idx = jj;
%                             current_min_dist = min_dist;
%                         end
%                     end
%                     % Get this disjoint's com
%                     ref_half_span_hw = getMontageSpan(app, montages(m_idx))./2 + app.buffer_px;
%                     
%                     this_edge_r = all_edges(I,2);
%                     % Determine distance along this axis between the edge
%                     % and the com of the current disjoint
%                     
%                     
%                     dr = getCenterToEdgeDistance(app, half_span_hw, thetas(ii));
%                     [x,y] = pol2cart(thetas(ii), this_edge_r + dr);
%                     com = [x,y];
%                     % todo: check that this doesn't result in any overlap
%                 else
%                     % The first montage has a center-of-mass of 0,0
%                     com = [0,0]; % x,y
%                 end
%                 [minx_tr(ii,1), minx_tr(ii,2)] = cart2pol(com(1) - half_span_hw(2), com(2));
%                 [maxx_tr(ii,1), maxx_tr(ii,2)] = cart2pol(com(1) + half_span_hw(2), com(2));
%                 [miny_tr(ii,1), miny_tr(ii,2)] = cart2pol(com(1), com(2) - half_span_hw(1));
%                 [maxy_tr(ii,1), maxy_tr(ii,2)] = cart2pol(com(1), com(2) + half_span_hw(1));
                
                % Get origin [IN IMAGE SPACE]
                mon_origins_yxhw(ii, :) =  [-all_anchors(1,1,2,ii), all_anchors(1,1,1,ii), span_hw]; % (-y)
            end
            
            % Determine canvas size
            app.canvas_hw = ceil([...
                max(mon_origins_yxhw(:,1) + mon_origins_yxhw(:,3)) - min(mon_origins_yxhw(:,1)), ...
                max(mon_origins_yxhw(:,2) + mon_origins_yxhw(:,4)) - min(mon_origins_yxhw(:,2))]);
            
            % Shift origins so that the mins are at y=1,x=1
            mon_origins_yxhw(:, 1:2) = mon_origins_yxhw(:, 1:2) - min(mon_origins_yxhw(:, 1:2), [], 1) + [1,1];
            
            % Overwrite txfms with shifts and return montages structure
            for ii=1:numel(montages)
                xyhw = txfms2mat(app, montages(ii).txfms);
                % Convert from centers to origins
                xyhw(:, 1:2) = xyhw(:, 1:2) - xyhw(:, 4:-1:3)./2;
                
                % Shift so that minx=0 and miny=0, then shift to montage
                % origin
                xyhw(:,1:2) = xyhw(:,1:2) - min(xyhw(:,1:2), [], 1) + mon_origins_yxhw(ii, 2:-1:1);
                
                % Overwrite 
                for jj=1:numel(montages(ii).txfms)
                    montages(ii).txfms{jj}{2} = xyhw(jj, 1); % overwrite x
                    montages(ii).txfms{jj}{3} = xyhw(jj, 2); % overwrite y
                end
            end
        end
        
        function [montages, closest_pos] = proximityToCenterSort(app, montages)
            % Shortcut, no need to rearrange if there's only one disjoint
            if numel(montages) == 1
                closest_pos = [0,0];
                return;
            end
            
            % Get location data, determine distance from center
            loc_data = getLocDataByEye(app, app.live_data.eye);
            vn = cell2mat(cellfun(@str2double, loc_data.vidnums, 'uniformoutput', false));
            xy = fixCoordsToMat(app.live_data.mon.loc_data.coords);
            dists = pdist2([0,0], xy);
            
            % Determine which disjoints are closest to the center
            montage_dists = inf(size(montages));
            closest_pos = zeros(numel(montage_dists), 2);
            for ii=1:numel(montages)
                for jj=1:numel(montages(ii).txfms)
                    img_ffname = montages(ii).txfms{jj}{1};
                    [~,img_name, img_ext] = fileparts(img_ffname);
                    kv = matchImgToVid(app.live_data.vid.vid_set, [img_name, img_ext]);
%                     kv = findImageInVidDB(app.live_data, [img_name, img_ext]);
                    if isempty(kv)
                        continue;
                    end
                    loc_data_idx = app.live_data.vid.vid_set(kv(1)).vidnum == vn;
                    if dists(loc_data_idx) < montage_dists(ii)
                        montage_dists(ii) = dists(loc_data_idx);
                        closest_pos(ii,:) = xy(loc_data_idx, :);
                    end
                end
            end
            
            % Sort by proximity to center
            [~,I] = sort(montage_dists, 'ascend');
            montages = montages(I);
            closest_pos = closest_pos(I,:);
        end
        
        
        function span_hw = getMontageSpan(app, mon)
            % Get coordinates of the origins rather than the centers
            xyhw = txfms2mat(app, mon.txfms);
            xyhw(:,1:2) = xyhw(:, 1:2) - xyhw(:, 4:-1:3)./2;
            minx = min(xyhw(:,1));
            maxx = max(xyhw(:,1) + xyhw(:,4));
            miny = min(xyhw(:,2));
            maxy = max(xyhw(:,2) + xyhw(:,3));
            span_hw = [maxy-miny, maxx-minx];
        end
                
        function loc_data = getLocDataByEye(app, this_eye)
            loc_data = app.live_data.mon.loc_data;
            wrong_eye = ~strcmpi(loc_data.eyes, this_eye);
            loc_data.vidnums(wrong_eye)     = [];
            loc_data.coords(wrong_eye, :)   = [];
            loc_data.fovs(wrong_eye)        = [];
            loc_data.eyes(wrong_eye)        = [];
            loc_data.fringes(wrong_eye)     = [];
        end
                
        function canvas = populateCanvas(app, montages)
            %% Create canvas and start populating
            canvas = zeros(app.canvas_hw(1), app.canvas_hw(2), 'uint8');
            
            % todo: add images in order of decreasing FOV, (small FOV likely
            % to have best resolution)
            for ii=1:numel(montages)
                xyhw = txfms2mat(app, montages(ii).txfms);
                
                for jj=1:numel(montages(ii).txfms)
                    % Read image, resize
                    img = imresize(imread(montages(ii).txfms{jj}{1}), ...
                        xyhw(jj,3:4), 'nearest');
                    im_size = size(img);
                    % Add to canvas
                    canvas(...
                        round(xyhw(jj,2):xyhw(jj,2)+im_size(1)-1), ...
                        round(xyhw(jj,1):xyhw(jj,1)+im_size(2)-1)) = img;
                end
                
                % Update progress
                updateProgress(app, ii/numel(montages));
            end
        end
        
        
        function updateProgress(app, value)
            pax_kids = get(app.prog_ax, 'children');
            if ~isempty(pax_kids)
                set(pax_kids, 'XData', [0, value, value, 0]);
                drawnow();
            end
        end
        
        
        function updateBreakDisplay(app)
            if numel(app.live_data.mon.montages) > 1
                suggested_locs = findBreaks(app.live_data, app.live_data.mon.montages, ...
                    app.live_data.mon.loc_data, app.live_data.mon.opts);
                break_cell = cell(size(suggested_locs, 1), 1);
                for ii=1:size(suggested_locs, 1)
                    break_cell{ii} = sprintf('%1.2f, %1.2f', suggested_locs(ii,1), suggested_locs(ii,2));
                end
                % Add header
                break_cell = [app.break_label; break_cell];
                % Update text
                app.break_text.Value = break_cell;
            else
                app.break_text.Value = {app.break_label; 'None'};
            end
        end
        
        
        function d = getCenterToEdgeDistance(~, hw, theta)
            if mod(theta, pi) == 0 % n*pi where n is an integer
                d = hw(2);
            elseif mod(theta, pi/2) == 0 % n*pi/2 where n is an integer
                d = hw(1);
            else
                theta_max = atan(hw(1)/hw(2));
                
                % Get the Q1 angle
                theta = abs(wrapToPi(theta));
                if theta > pi/2
                    theta = abs(wrapToPi(theta + pi));
                end
                
                if theta < theta_max
                    d = hw(2)/cos(theta);
                else
                    d = hw(1)/sin(theta);
                end
            end
%             % Quick hack to see if this helps prevent overlapping disjoints
%             d = sqrt(hw(1)^2 + hw(2)^2);
        end
        
        function drawDisjointPatches(app, yxhw)
            for ii=1:size(yxhw, 1)
                patch(app.montage_ax, ...
                    'xdata', round([yxhw(ii,2),yxhw(ii,2)+yxhw(ii,4),yxhw(ii,2)+yxhw(ii,4),yxhw(ii,2)]), ...
                    'ydata', round([yxhw(ii,1),yxhw(ii,1),yxhw(ii,1)+yxhw(ii,3),yxhw(ii,1)+yxhw(ii,3)]), ...
                    'facecolor','none','edgecolor','r')
            end
        end
        
        function xyhw = txfms2mat(~, txfms)
            xyhw = cell2mat(cellfun(@(x) [x{2}, x{3}, x{4}, x{5}], txfms', 'uniformoutput', false));
        end
        
        
        function updateLatestVidnum(app)
            % Get all video numbers, post the biggest ¯\(°_o)/¯
            mon = app.live_data.mon.montages;
            latestVidnum = 0;
            for ii=1:numel(mon)
                all_ffnames = cellfun(@(x) x{1}, mon(ii).txfms, 'uniformoutput', false)';
                [~, all_names, all_exts] = cellfun(@fileparts, all_ffnames, 'UniformOutput', false);
                all_fnames = cellfun(@(x,y) [x,y], all_names, all_exts, 'UniformOutput', false);
                
                all_vn = zeros(size(all_fnames));
                for jj=1:numel(all_vn)
                    key = matchImgToVid(app.live_data.vid.vid_set, all_fnames{jj});
                    all_vn(jj) = app.live_data.vid.vid_set(key(1)).vidnum;
                end
                latestVidnum = max([unique(all_vn); latestVidnum]);
            end
            app.latest_vidnum = latestVidnum;
            set(app.latest_vidnum_txt, 'value', sprintf('#%i', latestVidnum));
        end
        
        function drawLatestPatches(app, mon)
            % Draw a border around newest images added
            for ii=1:numel(mon)
                xyhw = txfms2mat(app, mon(ii).txfms);
                
                % Get video numbers that match the latest
                all_ffnames = cellfun(@(x) x{1}, mon(ii).txfms, 'uniformoutput', false)';
                [~, all_names, all_exts] = cellfun(@fileparts, all_ffnames, 'UniformOutput', false);
                all_fnames = cellfun(@(x,y) [x,y], all_names, all_exts, 'UniformOutput', false);
                all_vn = zeros(size(all_fnames));
                for jj=1:numel(all_vn)
                    key = matchImgToVid(app.live_data.vid.vid_set, all_fnames{jj});
                    all_vn(jj) = app.live_data.vid.vid_set(key(1)).vidnum;
                end
                % Filter out non-matching vidnums
                remove = all_vn ~= app.latest_vidnum;
                mon(ii).txfms(remove) = [];
                xyhw(remove, :) = [];
                
                for jj=1:numel(mon(ii).txfms)
                    patch(app.montage_ax, ...
                        'xdata', round([xyhw(jj,1),xyhw(jj,1)+xyhw(jj,4),xyhw(jj,1)+xyhw(jj,4),xyhw(jj,1)]), ...
                        'ydata', round([xyhw(jj,2),xyhw(jj,2),xyhw(jj,2)+xyhw(jj,3),xyhw(jj,2)+xyhw(jj,3)]), ...
                        'facecolor','none','edgecolor','w')
                end
            end
        end
        
        function anchors = getAnchors(~, xyhw)
            % get Anchors returns a 3x3x2 matrix where the first slice
            % contains x-values of the corners and edge centers and the
            % second slice is the y-values, e.g., anchors(1,1,:) is the x,y
            % pair for the top left corner
            % xyhw is the xy of the center, hw are height and width
            [xx, yy] = meshgrid(-1:1, 1:-1:-1);
            xx = xx.*(xyhw(4)/2) + xyhw(1);
            yy = yy.*(xyhw(3)/2) + xyhw(2);
            anchors = cat(3, xx, yy);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function montage_display_OpeningFcn(app, in_channels, live_data)
            if exist('in_channels', 'var') ~=0 && ~isempty(in_channels)
                % Determine if it's worthwhile to include wavelength
                incl_wl = numel(unique(in_channels(:,2))) > 1;
                
                mod_dd_items = cell(size(in_channels, 1), 1);
                for ii=1:numel(mod_dd_items)
                    if incl_wl
                        mod_dd_items{ii} = [in_channels{ii,1}, ':', in_channels{ii,2}];
                    else
                        mod_dd_items{ii} = in_channels{ii,1};
                    end
                end
                set(app.modality_dd, 'Items', mod_dd_items);
                set(app.modality_dd, 'Value', mod_dd_items{1});
            end
            
            if exist('live_data', 'var') ~= 0 && ~isempty(live_data)
                app.live_data = live_data;
                app.updateDisplay()
            end
            
            % Set up progress bar
            set(app.prog_ax, 'xlim', [0,1], 'ylim', [0,1])
            patch(app.prog_ax, [0, 0, 0, 0], [1, 1, 0, 0], [0, 0.6902, 0.9412]);
        end

        % Value changed function: modality_dd
        function modality_ddValueChanged(app, event)
            app.updateDisplay()
        end

        % Button pushed function: write_btn
        function write_btnButtonPushed(app, event)
            app.write_btn.Enable = 'off';
            outputMontage(app)
            app.write_btn.Enable = 'on';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'montage_display';
            app.montage_ax = uiaxes(app.figure1);
            app.montage_ax.Visible = 'off';
            app.modality_dd = uidropdown(app.figure1);
            app.modality_dd.Items = {'confocal', 'split_det', 'avg'};
            app.modality_dd.ValueChangedFcn = createCallbackFcn(app, @modality_ddValueChanged, true);
            app.modality_dd.Tooltip = {'This may take a moment to update'};
            app.modality_dd.Value = 'confocal';
            app.prog_ax = uiaxes(app.figure1);
            title(app.prog_ax, 'Title')
            xlabel(app.prog_ax, 'X')
            ylabel(app.prog_ax, 'Y')
            app.prog_ax.Visible = 'off';
            app.break_text = uitextarea(app.figure1);
            app.break_text.Value = {'Breaks'};
            app.write_btn = uibutton(app.figure1, 'push');
            app.write_btn.ButtonPushedFcn = createCallbackFcn(app, @write_btnButtonPushed, true);
            app.write_btn.Text = 'Write...';
            app.latest_vidnum_txt = uitextarea(app.figure1);
            app.latest_vidnum_txt.Value = {'#'};
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = montage_display_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @(app)montage_display_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end