classdef pipe_progress_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        fig                            matlab.ui.Figure
        SettingTabs                    matlab.ui.container.TabGroup
        AOSystemTab                    matlab.ui.container.Tab
        SaveAOSettingsButton           matlab.ui.control.Button
        AOSystemLabel                  matlab.ui.control.Label
        AOSystemDropDown               matlab.ui.control.DropDown
        CalibrationSettingsPanel       matlab.ui.container.Panel
        GridfrequencylpinLabel         matlab.ui.control.Label
        grid_freq                      matlab.ui.control.NumericEditField
        ModeleyefocallengthmmLabel     matlab.ui.control.Label
        grid_focal                     matlab.ui.control.NumericEditField
        OutputChannelsPanel            matlab.ui.container.Panel
        mod_order_uit                  matlab.ui.control.Table
        mod_up_btn                     matlab.ui.control.Button
        mod_dn_btn                     matlab.ui.control.Button
        add_mod_btn                    matlab.ui.control.Button
        del_mod_btn                    matlab.ui.control.Button
        edit_mod_btn                   matlab.ui.control.Button
        set_out_wl_btn                 matlab.ui.control.Button
        InputChannelsPanel             matlab.ui.container.Panel
        in_mods_uit                    matlab.ui.control.Table
        add_in_mod_btn                 matlab.ui.control.Button
        del_in_mod_btn                 matlab.ui.control.Button
        edit_in_mod_btn                matlab.ui.control.Button
        set_in_wl_btn                  matlab.ui.control.Button
        SessionTab                     matlab.ui.container.Tab
        RootPathLabel                  matlab.ui.control.Label
        root_txt                       matlab.ui.control.EditField
        browse_btn                     matlab.ui.control.Button
        CPUCoresLabel                  matlab.ui.control.Label
        cores_spin                     matlab.ui.control.Spinner
        LIVERegistrationSettingsPanel  matlab.ui.container.Panel
        live_frames_label              matlab.ui.control.Label
        n_frames_live_txt              matlab.ui.control.NumericEditField
        sr_live_cb                     matlab.ui.control.CheckBox
        run_live_cb                    matlab.ui.control.CheckBox
        FULLRegistrationSettingsPanel  matlab.ui.container.Panel
        MinFramesLabel                 matlab.ui.control.Label
        n_frames_full_txt              matlab.ui.control.NumericEditField
        path_valid                     matlab.ui.control.Lamp
        SubjectPanel                   matlab.ui.container.Panel
        subject_dd                     matlab.ui.control.DropDown
        subject_text_area              matlab.ui.control.TextArea
        HelpTab                        matlab.ui.container.Tab
        TextArea                       matlab.ui.control.TextArea
        instruct_btn                   matlab.ui.control.Button
        get_paper_btn                  matlab.ui.control.Button
        start_btn                      matlab.ui.control.Button
        quit_btn                       matlab.ui.control.Button
        finish_btn                     matlab.ui.control.Button
        ProgressTabs                   matlab.ui.container.TabGroup
        CalibrationTab                 matlab.ui.container.Tab
        dsin_uit                       matlab.ui.control.Table
        cal_path_txt                   matlab.ui.control.Label
        VideoTab                       matlab.ui.container.Tab
        vid_uit                        matlab.ui.control.Table
        raw_path_txt                   matlab.ui.control.Label
        MontageTab                     matlab.ui.container.Tab
        mon_path_txt                   matlab.ui.control.Label
        loc_file_txt                   matlab.ui.control.Label
        mon_uit                        matlab.ui.control.Table
    end

    % Custom properties that change the GUI or pipeline behavior
    properties (Access = public)
        paths       struct = [];                                                    % Path structure of the dataset
        n_cores     {mustBeNumeric, mustBeFinite, mustBePositive, isscalar} = 3;    % Number of CPU cores for parallel processing
        root_txt_bg_color = [1,1,1];
        mod_order_current_row = 1;  % Current selection of mod_order
        in_mod_current_row = 1;     % Current selection of input channel
        add_mod_defs = {'confocal', '680'}; % Default modality name and wavelength (nm)
        system_ini = [];
        finished logical = false; % Tells the pipeline that acquisition is over
        quitting logical = false; % Tells the pipeline to abort
        root_path_list = {}; % cell array of root_paths for full pipe
        start_full logical = false; % Start the full pipeline
        run_live logical = true;    % Can skip live feedback if desired
        subject char = 'Healthy Human'; % Defined by app.subject_dd
    end
    
    properties (Access = private)
        default_idle_time {mustBeNumeric, mustBeFinite, mustBePositive, isscalar} = 120; % Time in minutes before parallel pool shuts down
    end
    
    methods (Access = private)
        
        function reorder(app, idx)
            % Change the row order of a table. idx is the change in index
            % of the current selection
            current_table = app.mod_order_uit.Data;
            n_channels = size(current_table, 1);
            row_idx = app.mod_order_current_row;
            order = 1:n_channels;
            %fprintf('Current idx: %i, new idx: %i\n', row_idx, row_idx+idx);
            % Check for out of bounds
            if row_idx + idx <= 0 || row_idx + idx > n_channels
                fprintf('OOB, ignoring.\n');
                return;
            end
            order([row_idx, row_idx+idx]) = [row_idx+idx, row_idx];
            updated_table = current_table(order, :);
            % todo: figure out how to update the selected cell
            app.mod_order_uit.Data = updated_table;
        end    
        
        function valid = isValidWavelength(~, in)
            % Check that the wavelength given is a positive, real, finite number
            in = str2double(strtrim(in));
            valid = ~isempty(in) && ~isnan(in) && in > 0 && isreal(in) && ~isinf(in);
        end
        
        function valid = isValidModName(~,in)
            % Check that a modality name is just a non-empty character
            % array
            in = strtrim(in);
            valid = ~isempty(in) && ischar(in) && isnan(str2double(in));
        end
        
        function setAllWavelengths(app, uit_handle)
            this_uit_data = get(uit_handle, 'Data');
            if isempty(this_uit_data)
                return;
            end
            % Get user input
            re = inputdlg('Wavelength (nm)', 'Set all wavelengths', [1,50], {'790'});
            if isempty(re)
                return;
            end
            % Validate input
            if isValidWavelength(app, re{1})
                for ii=1:size(this_uit_data, 1)
                    this_uit_data{ii, 2} = re{1};
                end
                set(uit_handle, 'Data', this_uit_data);
            else
                uialert(app, 'Invalid input', 'Error');
            end
        end
        
        function addChannel(app, uit_handle, idx, replace)
            % Get current table
            current_table_data = get(uit_handle, 'Data');
            % If editing, set the defaults as the currently selected
            % channel
            in_chnl = [];
            if exist('replace','var') ~= 0 && ~isempty(replace) && replace
                in_chnl = current_table_data(idx, :);
            end
            
            % Get a user-defined channel
            chnl = getUserDefinedChannel(app, in_chnl);
            if isempty(chnl)
                % Canceled by user
                return;
            end
            
            % If idx is not given, add at the bottom
            if exist('idx', 'var') == 0 || isempty(idx)
                idx = size(current_table_data, 1) +1;
            end
            % Add row
            current_table_data = vertcat(current_table_data(1:idx, :), chnl, current_table_data(idx+1:end, :));
            % If replace, then remove the row before idx
            if exist('replace','var') ~= 0 && ~isempty(replace) && replace
                current_table_data(idx, :) = [];
            end
            % Update table
            set(uit_handle, 'Data', current_table_data);
        end
        
        function channel = getUserDefinedChannel(app, in_channel)
            % Set up dialogue box
            dlg_prompts = {'Modality label', 'Wavelength (nm)'};
            dlg_title = 'Enter channel details';
            dlg_size = [1,50];
            % Check for an input channel
            if exist('in_channel', 'var') == 0 || isempty(in_channel)
                in_channel = app.add_mod_defs;
            end
            
            % Get user input
            channel = inputdlg(dlg_prompts, dlg_title, dlg_size, in_channel)';
            if isempty(channel)
                % Canceled by user
                return;
            end
            % Validate input
            if ~isValidModName(app, channel{1})
                uialert(app.fig, 'Invalid modality name', 'Error', 'Icon', 'warning');
                channel = [];
            elseif ~isValidWavelength(app, channel{2})
                uialert(app.fig, sprintf('Invalid wavlength\n(must be only numbers)'), 'Error', 'Icon', 'warning');
                channel = [];
            end
        end
        
        function cpool = setupPool(app)
            %% Set up parallel pool
            if app.n_cores > 1 && exist('parfor', 'builtin') ~= 0
                cpool = gcp('nocreate');
                if isempty(cpool) || ~cpool.Connected || cpool.NumWorkers ~= app.n_cores
                    delete(gcp('nocreate'))
                    cpool = parpool(app.n_cores, 'IdleTimeout', app.default_idle_time);
                end
            end
        end
    end    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function pipe_progress_OpeningFcn(app)
            %% System tab
            % Set default table data
            % todo: there should probably be a whole function dedicated to
            % parsing the ini file as its structure may change and we need
            % to include some kind of version check
            app.system_ini = ini2struct('system.ini');
            fd_names = fieldnames(app.system_ini);
            is_ao_build = false(size(fd_names));
            for ii=1:numel(fd_names)
                is_ao_build(ii) = isfield(app.system_ini.(fd_names{ii}), 'ao_build');
            end
            ao_build = app.system_ini.(fd_names{find(is_ao_build, 1)});
            
            if isempty(ao_build)
                % I guess, leave them empty?
                return;
            end
            in_mods     = cellfun(@strtrim, strsplit(ao_build.in_modalities, ','), 'uniformoutput', false);
            out_mods    = cellfun(@strtrim, strsplit(ao_build.out_modalities, ','), 'uniformoutput', false);
            in_wl_nm    = cellfun(@strtrim, strsplit(ao_build.in_wavelengths_nm, ','), 'uniformoutput', false);
            out_wl_nm   = cellfun(@strtrim, strsplit(ao_build.in_wavelengths_nm, ','), 'uniformoutput', false);
            % Modality data
            app.in_mods_uit.Data    = horzcat(in_mods', in_wl_nm');
            app.mod_order_uit.Data  = horzcat(out_mods', out_wl_nm');
            % Calibration data
            app.grid_freq.Value     = ao_build.freq_lp_per_in;
            app.grid_focal.Value    = ao_build.focal_length_mm;
            
            % Modify system build drop down options
            ao_build_idx = find(is_ao_build);
            ao_build_names = cell(size(ao_build_idx));
            for ii=1:numel(ao_build_idx)
                ao_build_names{ii} = app.system_ini.(fd_names{ao_build_idx(ii)}).ao_build;
            end
            app.AOSystemDropDown.Items = ao_build_names;
            app.AOSystemDropDown.Value = ao_build_names{1};
            
            %% Session Tab
            % Cores
            this_pc_cluster = parcluster('local');
            max_n_workers = this_pc_cluster.NumWorkers;
            if max_n_workers < app.n_cores
                error('Serial processing not currently supported, need at least 3 cores')
            end
            app.cores_spin.Limits   = [3, max_n_workers];
            app.cores_spin.Value    = max_n_workers;
            app.n_cores = app.cores_spin.Value;
        end

        % Callback function
        function root_btnValueChanged(app, event)
            if ~app.root_btn.Value
                return;
            end
            usr_path = uigetdir('.', 'Select directory containing "Raw" and "Calibration" folders');
            if isnumeric(usr_path)
                return
            end
            % Guess paths, prompt if one is missing
            initiated_paths = initPaths(usr_path);
            
            set(app.root_txt, 'value', initiated_paths.root);
            app.paths = initiated_paths;
        end

        % Button pushed function: browse_btn
        function browse_btnPushed(app, event)
            usr_path = uigetdir('.', 'Select directory containing "Raw" and "Calibration" folders');
            if isnumeric(usr_path)
                return
            end
            % Guess paths, prompt if one is missing
            initiated_paths = initPaths(usr_path);
            
            set(app.root_txt, 'value', initiated_paths.root);
            root_txtValueChanged(app, event)
            app.paths = initiated_paths;
        end

        % Value changed function: root_txt
        function root_txtValueChanged(app, event)
            % Validate this entry and change behavior if invalid
            if exist(app.root_txt.Value, 'dir') == 0
                app.root_txt.BackgroundColor = [1, .25, .25];
                % Ensure start button is disabled
                app.start_btn.Enable = false;
                app.path_valid.Color = [0,0.25,0];
            else
                % So far, this is the only parameter that needs to be validated before running
                app.root_txt.BackgroundColor = app.root_txt_bg_color;
                app.start_btn.Enable = true;
                app.path_valid.Color = [0,1,0];
            end
        end

        % Value changed function: AOSystemDropDown
        function AOSystemDropDownValueChanged(app, event)
            value = app.AOSystemDropDown.Value;
            % Find the AO build that matches this tag
            fd_names = fieldnames(app.system_ini);
            ao_build = [];
            for ii=1:numel(fd_names)
                if isfield(app.system_ini.(fd_names{ii}), 'ao_build')
                    if strcmpi(value, app.system_ini.(fd_names{ii}).ao_build)
                        ao_build = app.system_ini.(fd_names{ii});
                        break;
                    end
                end
            end
            if isempty(ao_build)
                error('AO system not found')
            end
            % Apply this build to the tables
            % todo: this is a bit of a DRY-violation, could make a function
            % to apply an ao build to the tables
            in_mods     = cellfun(@strtrim, strsplit(ao_build.in_modalities, ','), 'uniformoutput', false);
            out_mods    = cellfun(@strtrim, strsplit(ao_build.out_modalities, ','), 'uniformoutput', false);
            in_wl_nm    = cellfun(@strtrim, strsplit(ao_build.in_wavelengths_nm, ','), 'uniformoutput', false);
            out_wl_nm   = cellfun(@strtrim, strsplit(ao_build.in_wavelengths_nm, ','), 'uniformoutput', false);
            % Modality data
            app.in_mods_uit.Data    = horzcat(in_mods', in_wl_nm');
            app.mod_order_uit.Data  = horzcat(out_mods', out_wl_nm');
            % Calibration data
            app.grid_freq.Value     = ao_build.freq_lp_per_in;
            app.grid_focal.Value    = ao_build.focal_length_mm;
        end

        % Button pushed function: mod_up_btn
        function mod_up_btnButtonPushed(app, event)
            reorder(app, -1);
        end

        % Cell selection callback: in_mods_uit
        function in_mods_uitCellSelection(app, event)
            app.in_mod_current_row = event.Indices(1);
        end

        % Cell selection callback: mod_order_uit
        function mod_order_uitCellSelection(app, event)
            app.mod_order_current_row = event.Indices(1);
            %fprintf('Current row: %i\n', event.Indices(1));
        end

        % Button pushed function: mod_dn_btn
        function mod_dn_btnButtonPushed(app, event)
            reorder(app, 1);
        end

        % Button pushed function: add_mod_btn
        function add_mod_btnButtonPushed(app, event)
            addChannel(app, app.mod_order_uit)
        end

        % Button pushed function: add_in_mod_btn
        function add_in_mod_btnButtonPushed(app, event)
            addChannel(app, app.in_mods_uit);
        end

        % Button pushed function: del_in_mod_btn
        function del_in_mod_btnButtonPushed(app, event)
            % Check that the currently selected row is not outside the
            % bounds
            % todo: figure out how to change the current selection
            c_row = app.in_mod_current_row;
            if c_row < 1 || c_row > size(app.in_mods_uit.Data, 1)
                return;
            end
            
            % Delete currently selected row
            app.in_mods_uit.Data(c_row, :) = [];
        end

        % Button pushed function: del_mod_btn
        function del_mod_btnButtonPushed(app, event)
            % Check that the currently selected row is not outside the
            % bounds
            % todo: figure out how to change the current selection
            c_row = app.mod_order_current_row;
            if c_row < 1 || c_row > size(app.mod_order_uit.Data, 1)
                return;
            end
            
            % Delete currently selected row
            app.mod_order_uit.Data(c_row, :) = [];
        end

        % Button pushed function: edit_in_mod_btn
        function edit_in_mod_btnButtonPushed(app, event)
            replace = true;
            addChannel(app, app.in_mods_uit, app.in_mod_current_row, replace);
        end

        % Button pushed function: edit_mod_btn
        function edit_mod_btnPushed(app, event)
            replace = true;
            addChannel(app, app.mod_order_uit, app.mod_order_current_row, replace);
            % Modify currently selected channel
            % todo: fix this minor DRY violation by creating a function
            % that adds and validates a channel. The only difference here
            % is the default values and the position.
            % Check if the table is empty
%             if isempty(app.mod_order_uit.Data)
%                 return;
%             end
%             
%             % Set up dialogue input
%             dlg_prompts = {'Modality label', 'Wavelength (nm)'};
%             dlg_title = 'Edit current channel';
%             dlg_size = [1,50];
%             dlg_defs = app.mod_order_uit.Data(app.mod_order_current_row, :)';
%             
%             re = inputdlg(dlg_prompts, dlg_title, dlg_size, dlg_defs);
%             if isempty(re)
%                 % Canceled by user
%                 return;
%             end
%             % Validate input
%             if isValidModName(app,re{1}) && isValidWavelength(app, re{2})
%                 % Add new channel to the bottom
%                 app.mod_order_uit.Data(app.mod_order_current_row, :) = re';
%             else
%                 % Warn the user that these are invalid, reset the defaults
%                 % in case there's just a small thing they need to fix
%                 msgbox('Invalid input')
%             end
        end

        % Button pushed function: set_in_wl_btn
        function set_in_wl_btnButtonPushed(app, event)
            setAllWavelengths(app, app.in_mods_uit)
        end

        % Button pushed function: set_out_wl_btn
        function set_out_wl_btnButtonPushed(app, event)
            setAllWavelengths(app, app.mod_order_uit)
        end

        % Value changed function: cores_spin
        function cores_spinValueChanged(app, event)
            app.n_cores = app.cores_spin.Value;
%             fprintf('Current # cores: %i\n', app.n_cores)
        end

        % Value changed function: run_live_cb
        function run_live_cbValueChanged(app, event)
            app.run_live = app.run_live_cb.Value;
            % Ensure full will run if live disabled
            app.start_full = ~app.run_live;
            % Disable live parameter selections
            enable_list = {'off', 'on'};
            set(app.sr_live_cb, 'enable', enable_list{app.run_live_cb.Value+1});
            set(app.live_frames_label, 'enable', enable_list{app.run_live_cb.Value+1});
            set(app.n_frames_live_txt, 'enable', enable_list{app.run_live_cb.Value+1});
        end

        % Button pushed function: start_btn
        function start_btnButtonPushed(app, event)
            app.start_btn.Enable = 'off';
            app.finish_btn.Enable = 'on';
            app.dsin_uit.Enable = 'on';
            app.vid_uit.Enable  = 'on';
            app.mon_uit.Enable  = 'on';
            app.finished = false;
            app.root_path_list = vertcat(app.root_path_list, {app.root_txt.Value});
            drawnow;
            % todo: Disable all settings, use a loop to find all
            % enable-able children and set to false
            
            % Set up parallel pool
%             app.parallelPoolObj = setupPool(app);
            ao_pipe('root_path', app.root_txt.Value, 'num_workers', app.n_cores, 'pipe_gui', app);
            % This doesn't work because it doesn't allow an app to be
            % passed through parfeval
%             parfeval(app.parallelPoolObj, ...
%                 @ao_pipe, 1, 'root_path', app.root_txt.Value, 'num_workers', app.n_cores, 'pipe_gui', app);
            
        end

        % Button pushed function: quit_btn
        function quit_btnButtonPushed(app, event)
            re = questdlg('Are you sure you want to quit?', 'Quit?','Yes','No','No');
            drawnow nocallbacks;
            if strcmp(re, 'Yes')
                app.quitting = true;
                drawnow;
                
                % Terminate pipe without finishing
                delete(app);
            end
        end

        % Button pushed function: finish_btn
        function finish_btnButtonPushed(app, event)
            re = questdlg('Are you sure you''re finished?', 'Done?','Yes','No','No');
            drawnow nocallbacks;
            if ~strcmp(re, 'Yes')
                return;
            end
            app.start_btn.Enable = 'on';
            % todo re-enable any settings if they were disabled
            
            % Check if they want to do full pipe or another live session
            re = questdlg('New session (switch eyes?) or start FULL pipe?', 'New session?','New', 'Full', 'Close', 'Close');
            switch re 
                case 'New'
                    app.finished = true;
                case 'Full'
                    app.start_full = true;
                    app.finished = true;
                case 'Close'
                    app.quitting = true;
                    app.finished = true;
                    delete(app);
                otherwise
                    return
            end
        end

        % Button pushed function: instruct_btn
        function instruct_btnButtonPushed(app, event)
            addpath('help')
            pdf_search = dir(fullfile('help','*.pdf'));
            try
                [~,I] = max([pdf_search.datenum]);
                pdf_file = pdf_search(I);
                open(fullfile(pdf_file.folder, pdf_file.name));
            catch
                uialert(app.fig,'Oh man, I am so sorry, I can''t help you','Help doc not found','Icon','warning');
            end
        end

        % Button pushed function: get_paper_btn
        function get_paper_btnButtonPushed(app, event)
            web('https://doi.org/10.1167/tvst.6.2.9')
        end

        % Value changed function: subject_dd
        function subject_ddValueChanged(app, event)
            value = app.subject_dd.Value;
            disp(value)
            app.subject = value;
            switch value
                case 'Healthy Human'
                    text = ['The confocal channel will be used to count cones in', ...
                        'the central 2 degrees, and split-detector everywhere else.'];
                case {'ACHM', '13-LGS'}
                    text = 'The split-detector channel will be used to count cones everywhere.';
                case 'Other'
                    text = ['We will use the "Healthy Human" settings,', ...
                        'which assumes normal waveguiding in the parafovea'];
            end
            app.subject_text_area.Value = text;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.fig = uifigure('Visible', 'off');
            app.fig.Name = 'AOSLO PIPELINE v1.0';
            app.SettingTabs = uitabgroup(app.fig);
            app.AOSystemTab = uitab(app.SettingTabs);
            app.AOSystemTab.Title = 'AO System';
            app.SaveAOSettingsButton = uibutton(app.AOSystemTab, 'push');
            app.SaveAOSettingsButton.Enable = 'off';
            app.SaveAOSettingsButton.Tooltip = {'Not yet implemented'; 'Saves the current system settings as default.'};
            app.SaveAOSettingsButton.Text = 'Save AO Settings';
            app.AOSystemLabel = uilabel(app.AOSystemTab);
            app.AOSystemLabel.Text = 'AO System: ';
            app.AOSystemDropDown = uidropdown(app.AOSystemTab);
            app.AOSystemDropDown.Items = {'2.3', '4.1', 'Animal'};
            app.AOSystemDropDown.ValueChangedFcn = createCallbackFcn(app, @AOSystemDropDownValueChanged, true);
            app.AOSystemDropDown.Value = '2.3';
            app.CalibrationSettingsPanel = uipanel(app.AOSystemTab);
            app.CalibrationSettingsPanel.Title = 'Calibration Settings';
            app.GridfrequencylpinLabel = uilabel(app.CalibrationSettingsPanel);
            app.GridfrequencylpinLabel.Text = 'Grid frequency (lp/in): ';
            app.grid_freq = uieditfield(app.CalibrationSettingsPanel, 'numeric');
            app.grid_freq.Limits = [2.22044604925031e-16 Inf];
            app.grid_freq.Tooltip = {'Frequency of Ronchi ruling used to measure the distortion caused by the resonant scanner and to calibrate the spatial scale of the system'};
            app.grid_freq.Value = 3000;
            app.ModeleyefocallengthmmLabel = uilabel(app.CalibrationSettingsPanel);
            app.ModeleyefocallengthmmLabel.Text = 'Model eye focal length (mm): ';
            app.grid_focal = uieditfield(app.CalibrationSettingsPanel, 'numeric');
            app.grid_focal.Limits = [0 Inf];
            app.grid_focal.Tooltip = {'Focal length of the lens used to image the Ronchi ruling.'};
            app.grid_focal.Value = 19;
            app.OutputChannelsPanel = uipanel(app.AOSystemTab);
            app.OutputChannelsPanel.Title = 'Output Channels';
            app.mod_order_uit = uitable(app.OutputChannelsPanel);
            app.mod_order_uit.ColumnName = {'Modality'; 'λ (nm)'};
            app.mod_order_uit.RowName = {};
            app.mod_order_uit.CellSelectionCallback = createCallbackFcn(app, @mod_order_uitCellSelection, true);
            app.mod_order_uit.Tooltip = {'Rank modalities by expected SNR (#1 will be the primary sequence first)'};
            app.mod_up_btn = uibutton(app.OutputChannelsPanel, 'push');
            app.mod_up_btn.ButtonPushedFcn = createCallbackFcn(app, @mod_up_btnButtonPushed, true);
            app.mod_up_btn.Tooltip = {'Increase the priority of the selected channel'};
            app.mod_up_btn.Text = '↑';
            app.mod_dn_btn = uibutton(app.OutputChannelsPanel, 'push');
            app.mod_dn_btn.ButtonPushedFcn = createCallbackFcn(app, @mod_dn_btnButtonPushed, true);
            app.mod_dn_btn.Tooltip = {'Decrease the priority of the selected channel'};
            app.mod_dn_btn.Text = '↓';
            app.add_mod_btn = uibutton(app.OutputChannelsPanel, 'push');
            app.add_mod_btn.ButtonPushedFcn = createCallbackFcn(app, @add_mod_btnButtonPushed, true);
            app.add_mod_btn.Tooltip = {'Add a channel'};
            app.add_mod_btn.Text = '+';
            app.del_mod_btn = uibutton(app.OutputChannelsPanel, 'push');
            app.del_mod_btn.ButtonPushedFcn = createCallbackFcn(app, @del_mod_btnButtonPushed, true);
            app.del_mod_btn.Tooltip = {'Delete this channel'};
            app.del_mod_btn.Text = '-';
            app.edit_mod_btn = uibutton(app.OutputChannelsPanel, 'push');
            app.edit_mod_btn.ButtonPushedFcn = createCallbackFcn(app, @edit_mod_btnPushed, true);
            app.edit_mod_btn.Tooltip = {'Edit the selected channel'};
            app.edit_mod_btn.Text = '...';
            app.set_out_wl_btn = uibutton(app.OutputChannelsPanel, 'push');
            app.set_out_wl_btn.ButtonPushedFcn = createCallbackFcn(app, @set_out_wl_btnButtonPushed, true);
            app.set_out_wl_btn.Tooltip = {'Change all wavelengths.'};
            app.set_out_wl_btn.Text = 'λ';
            app.InputChannelsPanel = uipanel(app.AOSystemTab);
            app.InputChannelsPanel.Title = 'Input Channels';
            app.in_mods_uit = uitable(app.InputChannelsPanel);
            app.in_mods_uit.ColumnName = {'Modality'; 'λ (nm)'};
            app.in_mods_uit.RowName = {};
            app.in_mods_uit.CellSelectionCallback = createCallbackFcn(app, @in_mods_uitCellSelection, true);
            app.in_mods_uit.Tooltip = {'Specify acquisition channels for this session'};
            app.add_in_mod_btn = uibutton(app.InputChannelsPanel, 'push');
            app.add_in_mod_btn.ButtonPushedFcn = createCallbackFcn(app, @add_in_mod_btnButtonPushed, true);
            app.add_in_mod_btn.Tooltip = {'Add a channel'};
            app.add_in_mod_btn.Text = '+';
            app.del_in_mod_btn = uibutton(app.InputChannelsPanel, 'push');
            app.del_in_mod_btn.ButtonPushedFcn = createCallbackFcn(app, @del_in_mod_btnButtonPushed, true);
            app.del_in_mod_btn.Tooltip = {'Delete this channel'};
            app.del_in_mod_btn.Text = '-';
            app.edit_in_mod_btn = uibutton(app.InputChannelsPanel, 'push');
            app.edit_in_mod_btn.ButtonPushedFcn = createCallbackFcn(app, @edit_in_mod_btnButtonPushed, true);
            app.edit_in_mod_btn.Tooltip = {'Edit this channel'};
            app.edit_in_mod_btn.Text = '...';
            app.set_in_wl_btn = uibutton(app.InputChannelsPanel, 'push');
            app.set_in_wl_btn.ButtonPushedFcn = createCallbackFcn(app, @set_in_wl_btnButtonPushed, true);
            app.set_in_wl_btn.Tooltip = {'Change all wavelengths.'};
            app.set_in_wl_btn.Text = 'λ';
            app.SessionTab = uitab(app.SettingTabs);
            app.SessionTab.Title = 'Session';
            app.RootPathLabel = uilabel(app.SessionTab);
            app.RootPathLabel.Text = 'Root Path: ';
            app.root_txt = uieditfield(app.SessionTab, 'text');
            app.root_txt.ValueChangedFcn = createCallbackFcn(app, @root_txtValueChanged, true);
            app.root_txt.Tooltip = {'Select the path that contains the "Raw" and "Calibration" folders'};
            app.browse_btn = uibutton(app.SessionTab, 'push');
            app.browse_btn.ButtonPushedFcn = createCallbackFcn(app, @browse_btnPushed, true);
            app.browse_btn.Text = 'Browse...';
            app.CPUCoresLabel = uilabel(app.SessionTab);
            app.CPUCoresLabel.Text = '# CPU Cores: ';
            app.cores_spin = uispinner(app.SessionTab);
            app.cores_spin.Limits = [3 4];
            app.cores_spin.RoundFractionalValues = 'on';
            app.cores_spin.ValueDisplayFormat = '%i';
            app.cores_spin.ValueChangedFcn = createCallbackFcn(app, @cores_spinValueChanged, true);
            app.cores_spin.Tooltip = {'Number of parallel threads to use. Serial processing not yet supported'};
            app.cores_spin.Value = 3;
            app.LIVERegistrationSettingsPanel = uipanel(app.SessionTab);
            app.LIVERegistrationSettingsPanel.Title = 'LIVE Registration Settings';
            app.live_frames_label = uilabel(app.LIVERegistrationSettingsPanel);
            app.live_frames_label.Text = '# Frames:';
            app.n_frames_live_txt = uieditfield(app.LIVERegistrationSettingsPanel, 'numeric');
            app.n_frames_live_txt.Limits = [0 Inf];
            app.n_frames_live_txt.Tooltip = {'A low # frames (~5) with SR is usually fast and effective.'; 'SR is not usually necessary in animals.'; 'Type Inf to guarantee all frames are used (not recommended).'};
            app.n_frames_live_txt.Value = 5;
            app.sr_live_cb = uicheckbox(app.LIVERegistrationSettingsPanel);
            app.sr_live_cb.Tooltip = {'Strip-registration takes longer but is necessary in humans'};
            app.sr_live_cb.Text = 'Strip-register';
            app.sr_live_cb.Value = true;
            app.run_live_cb = uicheckbox(app.LIVERegistrationSettingsPanel);
            app.run_live_cb.ValueChangedFcn = createCallbackFcn(app, @run_live_cbValueChanged, true);
            app.run_live_cb.Tooltip = {'Disable if  you only want to run the FULL pipeline'};
            app.run_live_cb.Text = 'Run LIVE PIPE';
            app.run_live_cb.Value = true;
            app.FULLRegistrationSettingsPanel = uipanel(app.SessionTab);
            app.FULLRegistrationSettingsPanel.Title = 'FULL Registration Settings';
            app.MinFramesLabel = uilabel(app.FULLRegistrationSettingsPanel);
            app.MinFramesLabel.Text = 'Min # Frames:';
            app.n_frames_full_txt = uieditfield(app.FULLRegistrationSettingsPanel, 'numeric');
            app.n_frames_full_txt.Limits = [0 Inf];
            app.n_frames_full_txt.Tooltip = {'The minimum # of frames registered to be considered successful'; '↑ with low SNR (e.g., cataract)'; '↓ with eye motion (e.g., nystagmus)'; ''};
            app.n_frames_full_txt.Value = 10;
            app.path_valid = uilamp(app.SessionTab);
            app.SubjectPanel = uipanel(app.SessionTab);
            app.SubjectPanel.Title = 'Subject';
            app.SubjectPanel.Visible = 'off';
            app.subject_dd = uidropdown(app.SubjectPanel);
            app.subject_dd.Items = {'Healthy Human', 'ACHM', '13-LGS', 'Other'};
            app.subject_dd.ValueChangedFcn = createCallbackFcn(app, @subject_ddValueChanged, true);
            app.subject_dd.Tooltip = {'Refines analysis parameters'};
            app.subject_dd.Value = 'Healthy Human';
            app.subject_text_area = uitextarea(app.SubjectPanel);
            app.subject_text_area.Value = {'This setting does not affect "LIVE" mode'};
            app.HelpTab = uitab(app.SettingTabs);
            app.HelpTab.Title = 'Help';
            app.TextArea = uitextarea(app.HelpTab);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'AOSLO Pipeline v. 1.0.x'; 'Created by Alex Salmon - 2020.02.xx'; 'Carroll Lab'; ''; 'If you use this program, please cite:'; 'Salmon, A.E., Cooper, R.F., Chen, M, Davidson, B, Higgins, B, Dubra, A, Gilat-Schmidt, T., Carroll, J. Automation of an image processing pipeline for adaptive optics scanning light ophthalmoscope images in humans and 13-lined ground squirrels" Biomed Opt Express. x(x-x)'};
            app.instruct_btn = uibutton(app.HelpTab, 'push');
            app.instruct_btn.ButtonPushedFcn = createCallbackFcn(app, @instruct_btnButtonPushed, true);
            app.instruct_btn.Text = 'Instructions...';
            app.get_paper_btn = uibutton(app.HelpTab, 'push');
            app.get_paper_btn.ButtonPushedFcn = createCallbackFcn(app, @get_paper_btnButtonPushed, true);
            app.get_paper_btn.Text = 'Open article...';
            app.start_btn = uibutton(app.fig, 'push');
            app.start_btn.ButtonPushedFcn = createCallbackFcn(app, @start_btnButtonPushed, true);
            app.start_btn.Enable = 'off';
            app.start_btn.Text = 'Start';
            app.quit_btn = uibutton(app.fig, 'push');
            app.quit_btn.ButtonPushedFcn = createCallbackFcn(app, @quit_btnButtonPushed, true);
            app.quit_btn.Text = 'Quit';
            app.finish_btn = uibutton(app.fig, 'push');
            app.finish_btn.ButtonPushedFcn = createCallbackFcn(app, @finish_btnButtonPushed, true);
            app.finish_btn.Enable = 'off';
            app.finish_btn.Text = 'Finish';
            app.ProgressTabs = uitabgroup(app.fig);
            app.CalibrationTab = uitab(app.ProgressTabs);
            app.CalibrationTab.Title = 'Calibration';
            app.dsin_uit = uitable(app.CalibrationTab);
            app.dsin_uit.ColumnName = {'Grid Vid Name'; 'H/V'; 'FOV (°)'; 'λ (nm)'; 'Matched Vid Name'; 'Calibration file'};
            app.dsin_uit.RowName = '';
            app.dsin_uit.ColumnEditable = [false false false false false false];
            app.dsin_uit.Enable = 'off';
            app.cal_path_txt = uilabel(app.CalibrationTab);
            app.cal_path_txt.Text = 'Calibration path: ';
            app.VideoTab = uitab(app.ProgressTabs);
            app.VideoTab.Title = 'Video';
            app.vid_uit = uitable(app.VideoTab);
            app.vid_uit.ColumnName = {'Video #'; 'Modalities'; 'R/A'; 'Montage'; 'Analysis'};
            app.vid_uit.RowName = '';
            app.vid_uit.ColumnEditable = [false false false false false];
            app.vid_uit.Enable = 'off';
            app.raw_path_txt = uilabel(app.VideoTab);
            app.raw_path_txt.Text = 'Video path: ';
            app.MontageTab = uitab(app.ProgressTabs);
            app.MontageTab.Title = 'Montage';
            app.mon_path_txt = uilabel(app.MontageTab);
            app.mon_path_txt.Text = 'Montage path: ';
            app.loc_file_txt = uilabel(app.MontageTab);
            app.loc_file_txt.Text = 'Location file: ';
            app.mon_uit = uitable(app.MontageTab);
            app.mon_uit.ColumnName = {'Video #'; 'Expected location (°)'; 'Placed location (°)'; 'Group #'; 'Not placed'};
            app.mon_uit.RowName = {};
            app.mon_uit.ColumnEditable = [false false false false false];
            app.mon_uit.Enable = 'off';
            % Show the figure after all components are created
            app.fig.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = pipe_progress_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.fig)

            % Execute the startup function
            runStartupFcn(app, @pipe_progress_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.fig)
        end
    end
end